classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        FlightpathTab                   matlab.ui.container.Tab
        NumberofWaypointsEditFieldLabel  matlab.ui.control.Label
        NumberofWaypointsEditField      matlab.ui.control.NumericEditField
        DrawFlightpathButton            matlab.ui.control.Button
        EmittersTab                     matlab.ui.container.Tab
        PlaceEmittersButton             matlab.ui.control.Button
        NumberofEmittersEditFieldLabel  matlab.ui.control.Label
        NumberofEmittersEditField       matlab.ui.control.NumericEditField
        BearingsTab_2                   matlab.ui.container.Tab
        GenerateBearingsButton          matlab.ui.control.Button
        OutlierTab                      matlab.ui.container.Tab
        PrepareSimulationLabel          matlab.ui.control.Label
        ShowFlightpathSwitchLabel       matlab.ui.control.Label
        ShowFlightpathSwitch            matlab.ui.control.Switch
        ShowEmittersSwitchLabel         matlab.ui.control.Label
        ShowEmittersSwitch              matlab.ui.control.Switch
        ShowBearingsSwitchLabel         matlab.ui.control.Label
        ShowBearingsSwitch              matlab.ui.control.Switch
        ShowEllipseSwitchLabel          matlab.ui.control.Label
        ShowEllipseSwitch               matlab.ui.control.Switch
    end


    properties (Access = public) %Setting some important Global storage points
        WayPointX = []; %X Points of Waypoints
        WayPointY = []; %Y Points of Waypoints
        FlightPathX = []; %X Points of Flight Path
        FlightPathY = []; %Y Points of FLight Path
        EstX = []; %X points of Estimate
        EstY = []; %Y points of Estimates
        EstXSort = []; %Sorted X Points
        EstYSort = []; %Sorted Y Points
        FlightPathLine = []; %Actual Line of Flight Path
        TrueEmitterX = []; %X Points of Emitters
        TrueEmitterY = []; %Y Points of Emitters
        numWays = 0; %number of waypoints chosen
        numEmit = 0; %number of Emmitters Chosen
        AirSpeedMPH = 700; %mph staying subsonic
        AirSpeedMPS = .1944444; %Miles Per Second Air Speed
        SampFactor = 4; %Number of samples taken per second
        ShowPath = 'Off';
        ShowEmit = 'Off';
        ShowBear = 'Off';
        ShowElip = 'Off';
        
        % Description
    end

    methods (Access = private)
    
        function results = ReDraw(app)
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            if strcmp(app.ShowPath, 'On') == 1
                scatter(app.UIAxes, app.FlightPathX, app.FlightPathY)
            end
            if strcmp(app.ShowEmit, 'On') == 1
                scatter(app.UIAxes, app.TrueEmitterX, app.TrueEmitterY)
            end
            if strcmp(app.ShowBear, 'On') == 1
            end
                
            if strcmp(app.ShowElip, 'On') == 1
                
            end
        end
        
        function results = upRamp(app,v,n,start)
            n = abs(n);
            for k = 1:n
                nxt = start + k;
                v = [v nxt];
            end
            results = v;
            
        end
        
        function results = downRamp(app,v,n,start)
            n = abs(n);
            for k = 1:n
                nxt = start - k;
                v = [v nxt];
            end
            results = v;
        end
        
        function results = RemoveOutliers(vec,p)
            %vec is the vector to remove from, p is the percentage to remove
            sz = size(vec,2);
            pr = p/2;
            nr = round(pr*sz); % number of elements to remove from the top/bot of vec
            bot = nr + 1;
            veca = vec(:,bot:end);
            sz2 = size(veca,2);
            top = sz2 - nr;
            vecb = veca(:,1:top);
            results = vecb;
        end
        
    end


    methods (Access = private)

        % Value changed function: NumberofWaypointsEditField
        function NumberofWaypointsEditFieldValueChanged(app, event)
            app.numWays = app.NumberofWaypointsEditField.Value;
            
        end

        % Button pushed function: DrawFlightpathButton
        function DrawFlightpathButtonPushed(app, event)
            MyFigure = figure;
            grid on
            xlim([0,100])
            ylim([0,100])
            [app.WayPointX, app.WayPointY]=ginput(app.numWays);
            delete(MyFigure);
            
            for k = 1:app.numWays-1
                x0 = app.WayPointX(k);
                x1 = app.WayPointX(k+1);
                y0 = app.WayPointY(k);
                y1 = app.WayPointY(k+1);
                yd = y1-y0;
                xd = x1-x0;
                if (yd > 0) && (xd > 0)
                    app.FlightPathY = upRamp(app,app.FlightPathY,yd,app.WayPointY(k));
                    app.FlightPathX = upRamp(app,app.FlightPathX,xd,app.WayPointX(k));
                    
                elseif (yd > 0 ) && (xd < 0)
                    app.FlightPathY = upRamp(app,app.FlightPathY,yd,app.WayPointY(k));
                    app.FlightPathX = downRamp(app,app.FlightPathX,xd,app.WayPointX(k));
                    
                elseif (yd < 0 ) && (xd > 0)
                    app.FlightPathY = downRamp(app,app.FlightPathY,yd,app.WayPointY(k));
                    app.FlightPathX = upRamp(app,app.FlightPathX,xd,app.WayPointX(k));                        
                elseif (yd < 0 ) && (xd < 0)
                    app.FlightPathY = downRamp(app,app.FlightPathY,yd,app.WayPointY(k));
                    app.FlightPathX = downRamp(app,app.FlightPathX,xd,app.WayPointX(k));
              
                end
            end


            ReDraw(app)
        end

        % Value changed function: NumberofEmittersEditField
        function NumberofEmittersEditFieldValueChanged(app, event)
            app.numEmit = app.NumberofEmittersEditField.Value;
            
        end

        % Value changed function: ShowFlightpathSwitch
        function ShowFlightpathSwitchValueChanged(app, event)
            app.ShowPath = app.ShowFlightpathSwitch.Value;
            ReDraw(app)
            
        end

        % Value changed function: ShowEmittersSwitch
        function ShowEmittersSwitchValueChanged(app, event)
            app.ShowEmit = app.ShowEmittersSwitch.Value;
            ReDraw(app)
            
        end

        % Value changed function: ShowBearingsSwitch
        function ShowBearingsSwitchValueChanged(app, event)
            app.ShowBear = app.ShowBearingsSwitch.Value;
            ReDraw(app)
            
        end

        % Value changed function: ShowEllipseSwitch
        function ShowEllipseSwitchValueChanged(app, event)
            app.ShowElip = app.ShowEllipseSwitch.Value;
            ReDraw(app)
            
        end

        % Button pushed function: PlaceEmittersButton
        function PlaceEmittersButtonPushed(app, event)
            MyFigure = figure;
            grid on
            xlim([0,100])
            ylim([0,100])
            [app.TrueEmitterX, app.TrueEmitterY] =ginput(app.numEmit);
            delete(MyFigure);
            ReDraw(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'West/East (NM)')
            ylabel(app.UIAxes, 'North/South (NM)')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FlightpathTab = uitab(app.TabGroup);
            app.FlightpathTab.Title = 'Flightpath';
            app.NumberofWaypointsEditFieldLabel = uilabel(app.FlightpathTab);
            app.NumberofWaypointsEditFieldLabel.Text = 'Number of Waypoints';
            app.NumberofWaypointsEditField = uieditfield(app.FlightpathTab, 'numeric');
            app.NumberofWaypointsEditField.Limits = [0 10];
            app.NumberofWaypointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofWaypointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofWaypointsEditFieldValueChanged, true);
            app.DrawFlightpathButton = uibutton(app.FlightpathTab, 'push');
            app.DrawFlightpathButton.ButtonPushedFcn = createCallbackFcn(app, @DrawFlightpathButtonPushed, true);
            app.DrawFlightpathButton.Text = 'Draw Flightpath';
            app.EmittersTab = uitab(app.TabGroup);
            app.EmittersTab.Title = 'Emitters';
            app.PlaceEmittersButton = uibutton(app.EmittersTab, 'push');
            app.PlaceEmittersButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceEmittersButtonPushed, true);
            app.PlaceEmittersButton.Text = 'Place Emitters';
            app.NumberofEmittersEditFieldLabel = uilabel(app.EmittersTab);
            app.NumberofEmittersEditFieldLabel.Text = 'Number of Emitters';
            app.NumberofEmittersEditField = uieditfield(app.EmittersTab, 'numeric');
            app.NumberofEmittersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofEmittersEditFieldValueChanged, true);
            app.BearingsTab_2 = uitab(app.TabGroup);
            app.BearingsTab_2.Title = 'Bearings';
            app.GenerateBearingsButton = uibutton(app.BearingsTab_2, 'push');
            app.GenerateBearingsButton.Text = 'Generate Bearings';
            app.OutlierTab = uitab(app.TabGroup);
            app.OutlierTab.Title = 'Outlier';
            app.PrepareSimulationLabel = uilabel(app.UIFigure);
            app.PrepareSimulationLabel.Text = 'Prepare Simulation';
            app.ShowFlightpathSwitchLabel = uilabel(app.UIFigure);
            app.ShowFlightpathSwitchLabel.Text = 'Show Flightpath';
            app.ShowFlightpathSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowFlightpathSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowFlightpathSwitchValueChanged, true);
            app.ShowEmittersSwitchLabel = uilabel(app.UIFigure);
            app.ShowEmittersSwitchLabel.Text = 'Show Emitters';
            app.ShowEmittersSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowEmittersSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowEmittersSwitchValueChanged, true);
            app.ShowBearingsSwitchLabel = uilabel(app.UIFigure);
            app.ShowBearingsSwitchLabel.Text = 'Show Bearings';
            app.ShowBearingsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowBearingsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowBearingsSwitchValueChanged, true);
            app.ShowEllipseSwitchLabel = uilabel(app.UIFigure);
            app.ShowEllipseSwitchLabel.Text = 'Show Ellipse';
            app.ShowEllipseSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowEllipseSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowEllipseSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end